classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LoadDataTab                   matlab.ui.container.Tab
        FileNameEditFieldLabel        matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        BoardsTab                     matlab.ui.container.Tab
        GSEControllerPanel_2          matlab.ui.container.Panel
        ValveChannelsTextArea_2       matlab.ui.control.TextArea
        ValveChannelsTextArea_2Label  matlab.ui.control.Label
        PTChannelsTextArea_2          matlab.ui.control.TextArea
        PTChannelsTextArea_2Label     matlab.ui.control.Label
        EngineControllerPanel_2       matlab.ui.container.Panel
        PTChannelsTextAreaLabel       matlab.ui.control.Label
        PTChannelsTextArea            matlab.ui.control.TextArea
        ValveChannelsTextArea         matlab.ui.control.TextArea
        ValveChannelsTextAreaLabel    matlab.ui.control.Label
        PlotsTab                      matlab.ui.container.Tab
        BoardsLabel                   matlab.ui.control.Label
        GSEControllerCheckBox         matlab.ui.control.CheckBox
        EngineControllerCheckBox      matlab.ui.control.CheckBox
        GSEControllerPanel            matlab.ui.container.Panel
        PTsLabel                      matlab.ui.control.Label
        ValvesLabel                   matlab.ui.control.Label
        CheckBox_9                    matlab.ui.control.CheckBox
        CheckBox_10                   matlab.ui.control.CheckBox
        CheckBox_11                   matlab.ui.control.CheckBox
        CheckBox_12                   matlab.ui.control.CheckBox
        CheckBox_13                   matlab.ui.control.CheckBox
        CheckBox_14                   matlab.ui.control.CheckBox
        CheckBox_15                   matlab.ui.control.CheckBox
        CheckBox_16                   matlab.ui.control.CheckBox
        CheckBox_17                   matlab.ui.control.CheckBox
        CheckBox_18                   matlab.ui.control.CheckBox
        CheckBox_19                   matlab.ui.control.CheckBox
        CheckBox_20                   matlab.ui.control.CheckBox
        CheckBox_21                   matlab.ui.control.CheckBox
        CheckBox_22                   matlab.ui.control.CheckBox
        CheckBox_23                   matlab.ui.control.CheckBox
        CheckBox_24                   matlab.ui.control.CheckBox
        CheckBox_25                   matlab.ui.control.CheckBox
        CheckBox_26                   matlab.ui.control.CheckBox
        CheckBox_27                   matlab.ui.control.CheckBox
        CheckBox_28                   matlab.ui.control.CheckBox
        CheckBox_29                   matlab.ui.control.CheckBox
        CheckBox_30                   matlab.ui.control.CheckBox
        CheckBox_31                   matlab.ui.control.CheckBox
        CheckBox_32                   matlab.ui.control.CheckBox
        LoadCellsLabel                matlab.ui.control.Label
        SelectFigureDropDownLabel     matlab.ui.control.Label
        SelectFigureDropDown          matlab.ui.control.DropDown
        FigureNamesTextAreaLabel      matlab.ui.control.Label
        FigureNamesTextArea           matlab.ui.control.TextArea
        EngineControllerPanel         matlab.ui.container.Panel
        PTsLabel_2                    matlab.ui.control.Label
        ValvesLabel_2                 matlab.ui.control.Label
        CheckBox_33                   matlab.ui.control.CheckBox
        CheckBox_34                   matlab.ui.control.CheckBox
        CheckBox_35                   matlab.ui.control.CheckBox
        CheckBox_36                   matlab.ui.control.CheckBox
        CheckBox_37                   matlab.ui.control.CheckBox
        CheckBox_38                   matlab.ui.control.CheckBox
        CheckBox_39                   matlab.ui.control.CheckBox
        CheckBox_40                   matlab.ui.control.CheckBox
        CheckBox_41                   matlab.ui.control.CheckBox
        CheckBox_42                   matlab.ui.control.CheckBox
        CheckBox_43                   matlab.ui.control.CheckBox
        CheckBox_44                   matlab.ui.control.CheckBox
        CheckBox_45                   matlab.ui.control.CheckBox
        CheckBox_46                   matlab.ui.control.CheckBox
        CheckBox_47                   matlab.ui.control.CheckBox
        CheckBox_48                   matlab.ui.control.CheckBox
        CheckBox_49                   matlab.ui.control.CheckBox
        CheckBox_50                   matlab.ui.control.CheckBox
        CheckBox_51                   matlab.ui.control.CheckBox
        CheckBox_52                   matlab.ui.control.CheckBox
        CheckBox_53                   matlab.ui.control.CheckBox
        CheckBox_54                   matlab.ui.control.CheckBox
        CheckBox_55                   matlab.ui.control.CheckBox
        CheckBox_56                   matlab.ui.control.CheckBox
        LoadCellsLabel_2              matlab.ui.control.Label
        TitleEditFieldLabel           matlab.ui.control.Label
        TitleEditField                matlab.ui.control.EditField
        xlabelEditFieldLabel          matlab.ui.control.Label
        xlabelEditField               matlab.ui.control.EditField
        ylabelEditFieldLabel          matlab.ui.control.Label
        ylabelEditField               matlab.ui.control.EditField
        ylimEditFieldLabel            matlab.ui.control.Label
        ylimEditField                 matlab.ui.control.EditField
        xlimEditFieldLabel            matlab.ui.control.Label
        xlimEditField                 matlab.ui.control.EditField
        RunButton                     matlab.ui.control.Button
        OptionsTab                    matlab.ui.container.Tab
        SelectFigureDropDown_2Label   matlab.ui.control.Label
        SelectFigureDropDown_2        matlab.ui.control.DropDown
        LegendCheckBox                matlab.ui.control.CheckBox
        ColorSchemeDropDownLabel      matlab.ui.control.Label
        ColorSchemeDropDown           matlab.ui.control.DropDown
        LineThicknessEditFieldLabel   matlab.ui.control.Label
        LineThicknessEditField        matlab.ui.control.EditField
        LineSymbolsEditFieldLabel     matlab.ui.control.Label
        LineSymbolsEditField          matlab.ui.control.EditField
        Tab                           matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function BoardSelected(app, event)
            value = app.SelectedBoardDropDown.Value;
            
            switch value
                case "Select Board"
                    app.GSEControllerPanel_2.Visible = 0;
                    app.EngineControllerPanel_2.Visible = 0;
                case "GSE Controller"
                    app.GSEControllerPanel_2.Visible = 1;
                    app.EngineControllerPanel_2.Visible = 0;
                case "Engine Controller"
                    app.GSEControllerPanel_2.Visible = 0;
                    app.EngineControllerPanel_2.Visible = 1;
            end
        end

        % Value changed function: FigureNamesTextArea
        function FigureNames(app, event)
            value = app.FigureNamesTextArea.Value;
            app.SelectFigureDropDown.Items = value;
            app.SelectFigureDropDown_2.Items = value;
            app.FiguresTabGroup = uitabgroup(app.Tab);
%             app.FiguresTabGroup.Position = [1 1 441 454];
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            data = readtable(app.FileNameEditField.Value);
            data_length = length(table2array(data(:,1)));
            global inputs;
            hold(app.UIAxes, 'on');
            for i = 1:length(inputs.engine_controller.pts.number)
                plot(app.UIAxes, 1:data_length, table2array(data(:,strcat("press_pressure_"+num2str(inputs.engine_controller.pts.number(i))+"__psi_"))),'displayname',inputs.engine_controller.pts.name{i});
            end
            legend(app.UIAxes);
        end

        % Value changed function: PTChannelsTextArea
        function EC_PT_text_changed(app, event)
            value = app.PTChannelsTextArea.Value;
            clear global;
            global inputs;
            cla(app.UIAxes,'reset');
            for i = 1:length(value)
                row = strsplit(value{i});
                inputs.engine_controller.pts.number(i) = str2double(row(1));
                inputs.engine_controller.pts.name(i) = {[row{2:end}]};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.FileNameEditFieldLabel = uilabel(app.LoadDataTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.LoadDataTab, 'text');
            app.FileNameEditField.Value = '20210824184746_presstest1_data_log';
            app.BoardsTab = uitab(app.TabGroup);
            app.BoardsTab.Title = 'Boards';
            app.GSEControllerPanel_2 = uipanel(app.BoardsTab);
            app.GSEControllerPanel_2.Title = 'GSE Controller';
            app.ValveChannelsTextArea_2 = uitextarea(app.GSEControllerPanel_2);
            app.ValveChannelsTextArea_2Label = uilabel(app.GSEControllerPanel_2);
            app.ValveChannelsTextArea_2Label.Text = 'Valve Channels';
            app.PTChannelsTextArea_2 = uitextarea(app.GSEControllerPanel_2);
            app.PTChannelsTextArea_2Label = uilabel(app.GSEControllerPanel_2);
            app.PTChannelsTextArea_2Label.Text = 'PT Channels';
            app.EngineControllerPanel_2 = uipanel(app.BoardsTab);
            app.EngineControllerPanel_2.Title = 'Engine Controller';
            app.PTChannelsTextAreaLabel = uilabel(app.EngineControllerPanel_2);
            app.PTChannelsTextAreaLabel.Text = 'PT Channels';
            app.PTChannelsTextArea = uitextarea(app.EngineControllerPanel_2);
            app.PTChannelsTextArea.ValueChangedFcn = createCallbackFcn(app, @EC_PT_text_changed, true);
            app.ValveChannelsTextArea = uitextarea(app.EngineControllerPanel_2);
            app.ValveChannelsTextAreaLabel = uilabel(app.EngineControllerPanel_2);
            app.ValveChannelsTextAreaLabel.Text = 'Valve Channels';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.BoardsLabel = uilabel(app.PlotsTab);
            app.BoardsLabel.Text = 'Boards:';
            app.GSEControllerCheckBox = uicheckbox(app.PlotsTab);
            app.GSEControllerCheckBox.Text = 'GSE Controller';
            app.EngineControllerCheckBox = uicheckbox(app.PlotsTab);
            app.EngineControllerCheckBox.Text = 'Engine Controller';
            app.GSEControllerPanel = uipanel(app.PlotsTab);
            app.GSEControllerPanel.Title = 'GSE Controller';
            app.PTsLabel = uilabel(app.GSEControllerPanel);
            app.PTsLabel.Text = 'PTs';
            app.ValvesLabel = uilabel(app.GSEControllerPanel);
            app.ValvesLabel.Text = 'Valves';
            app.CheckBox_9 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_9.Text = '1';
            app.CheckBox_10 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_10.Text = '2';
            app.CheckBox_11 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_11.Text = '4';
            app.CheckBox_12 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_12.Text = '3';
            app.CheckBox_13 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_13.Text = '8';
            app.CheckBox_14 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_14.Text = '7';
            app.CheckBox_15 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_15.Text = '6';
            app.CheckBox_16 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_16.Text = '5';
            app.CheckBox_17 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_17.Text = '8';
            app.CheckBox_18 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_18.Text = '7';
            app.CheckBox_19 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_19.Text = '6';
            app.CheckBox_20 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_20.Text = '5';
            app.CheckBox_21 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_21.Text = '4';
            app.CheckBox_22 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_22.Text = '3';
            app.CheckBox_23 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_23.Text = '2';
            app.CheckBox_24 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_24.Text = '1';
            app.CheckBox_25 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_25.Text = '8';
            app.CheckBox_26 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_26.Text = '7';
            app.CheckBox_27 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_27.Text = '6';
            app.CheckBox_28 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_28.Text = '5';
            app.CheckBox_29 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_29.Text = '4';
            app.CheckBox_30 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_30.Text = '3';
            app.CheckBox_31 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_31.Text = '2';
            app.CheckBox_32 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_32.Text = '1';
            app.LoadCellsLabel = uilabel(app.GSEControllerPanel);
            app.LoadCellsLabel.Text = 'Load Cells';
            app.SelectFigureDropDownLabel = uilabel(app.PlotsTab);
            app.SelectFigureDropDownLabel.Text = 'Select Figure:';
            app.SelectFigureDropDown = uidropdown(app.PlotsTab);
            app.SelectFigureDropDown.Items = {};
            app.SelectFigureDropDown.Value = {};
            app.FigureNamesTextAreaLabel = uilabel(app.PlotsTab);
            app.FigureNamesTextAreaLabel.Text = 'Figure Names';
            app.FigureNamesTextArea = uitextarea(app.PlotsTab);
            app.FigureNamesTextArea.ValueChangedFcn = createCallbackFcn(app, @FigureNames, true);
            app.EngineControllerPanel = uipanel(app.PlotsTab);
            app.EngineControllerPanel.Title = 'Engine Controller';
            app.PTsLabel_2 = uilabel(app.EngineControllerPanel);
            app.PTsLabel_2.Text = 'PTs';
            app.ValvesLabel_2 = uilabel(app.EngineControllerPanel);
            app.ValvesLabel_2.Text = 'Valves';
            app.CheckBox_33 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_33.Text = '1';
            app.CheckBox_34 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_34.Text = '2';
            app.CheckBox_35 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_35.Text = '4';
            app.CheckBox_36 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_36.Text = '3';
            app.CheckBox_37 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_37.Text = '8';
            app.CheckBox_38 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_38.Text = '7';
            app.CheckBox_39 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_39.Text = '6';
            app.CheckBox_40 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_40.Text = '5';
            app.CheckBox_41 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_41.Text = '8';
            app.CheckBox_42 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_42.Text = '7';
            app.CheckBox_43 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_43.Text = '6';
            app.CheckBox_44 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_44.Text = '5';
            app.CheckBox_45 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_45.Text = '4';
            app.CheckBox_46 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_46.Text = '3';
            app.CheckBox_47 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_47.Text = '2';
            app.CheckBox_48 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_48.Text = '1';
            app.CheckBox_49 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_49.Text = '8';
            app.CheckBox_50 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_50.Text = '7';
            app.CheckBox_51 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_51.Text = '6';
            app.CheckBox_52 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_52.Text = '5';
            app.CheckBox_53 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_53.Text = '4';
            app.CheckBox_54 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_54.Text = '3';
            app.CheckBox_55 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_55.Text = '2';
            app.CheckBox_56 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_56.Text = '1';
            app.LoadCellsLabel_2 = uilabel(app.EngineControllerPanel);
            app.LoadCellsLabel_2.Text = 'Load Cells';
            app.TitleEditFieldLabel = uilabel(app.PlotsTab);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.PlotsTab, 'text');
            app.xlabelEditFieldLabel = uilabel(app.PlotsTab);
            app.xlabelEditFieldLabel.Text = 'xlabel:';
            app.xlabelEditField = uieditfield(app.PlotsTab, 'text');
            app.ylabelEditFieldLabel = uilabel(app.PlotsTab);
            app.ylabelEditFieldLabel.Text = 'ylabel:';
            app.ylabelEditField = uieditfield(app.PlotsTab, 'text');
            app.ylimEditFieldLabel = uilabel(app.PlotsTab);
            app.ylimEditFieldLabel.Text = 'ylim:';
            app.ylimEditField = uieditfield(app.PlotsTab, 'text');
            app.xlimEditFieldLabel = uilabel(app.PlotsTab);
            app.xlimEditFieldLabel.Text = 'xlim:';
            app.xlimEditField = uieditfield(app.PlotsTab, 'text');
            app.RunButton = uibutton(app.PlotsTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.SelectFigureDropDown_2Label = uilabel(app.OptionsTab);
            app.SelectFigureDropDown_2Label.Text = 'Select Figure:';
            app.SelectFigureDropDown_2 = uidropdown(app.OptionsTab);
            app.SelectFigureDropDown_2.Items = {};
            app.SelectFigureDropDown_2.Value = {};
            app.LegendCheckBox = uicheckbox(app.OptionsTab);
            app.LegendCheckBox.Text = 'Legend';
            app.ColorSchemeDropDownLabel = uilabel(app.OptionsTab);
            app.ColorSchemeDropDownLabel.Text = 'Color Scheme:';
            app.ColorSchemeDropDown = uidropdown(app.OptionsTab);
            app.ColorSchemeDropDown.Items = {'Select One', 'Jet', 'Colorful'};
            app.ColorSchemeDropDown.Value = 'Select One';
            app.LineThicknessEditFieldLabel = uilabel(app.OptionsTab);
            app.LineThicknessEditFieldLabel.Text = 'Line Thickness:';
            app.LineThicknessEditField = uieditfield(app.OptionsTab, 'text');
            app.LineSymbolsEditFieldLabel = uilabel(app.OptionsTab);
            app.LineSymbolsEditFieldLabel.Text = 'Line Symbols:';
            app.LineSymbolsEditField = uieditfield(app.OptionsTab, 'text');
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end